classdef EditSegmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        PanelImage                      matlab.ui.container.Panel
        UIAxesSlice                     matlab.ui.control.UIAxes
        OpcionesVolumenPanel            matlab.ui.container.Panel
        CerrarButton                    matlab.ui.control.Button
        VerButton                       matlab.ui.control.Button
        SuavizarButton_2                matlab.ui.control.Button
        VolumenSegmentadoLabel          matlab.ui.control.Label
        VolumenTextArea                 matlab.ui.control.TextArea
        DisminuirButton_2               matlab.ui.control.Button
        AumentarButton_2                matlab.ui.control.Button
        SliceSlider                     matlab.ui.control.Slider
        VolumenPanel                    matlab.ui.container.Panel
        CopiarsiguienteButton           matlab.ui.control.Button
        CopiaranteriorButton            matlab.ui.control.Button
        GuardarButton                   matlab.ui.control.Button
        AnteriorButton                  matlab.ui.control.Button
        SiguienteButton                 matlab.ui.control.Button
        OpcionesSlicePanel              matlab.ui.container.Panel
        SegmentacinsemiautomticaButton  matlab.ui.control.Button
        SuavizarButton                  matlab.ui.control.Button
        DisminuirButton                 matlab.ui.control.Button
        AumentarButton                  matlab.ui.control.Button
        DeshacertodosloscambiosButton   matlab.ui.control.Button
        DibujarElipseButton             matlab.ui.control.Button
        RecortarButton                  matlab.ui.control.Button
        PerfilarButton                  matlab.ui.control.Button
        VistaButtonGroup                matlab.ui.container.ButtonGroup
        CORONALButton                   matlab.ui.control.RadioButton
        SAGITALButton                   matlab.ui.control.RadioButton
        AXIALButton                     matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        Info; % volume data
        CallingApp % Main app object
    end
    
    properties (Access = private)
        posicionX % Description
        posicionY % Description
        posicionZ
        DIM % Description
    end
    
    methods (Access = private)
        
        function imrefresca(app)

            refrescaSlider(app);
            
            app.UIAxesSlice.reset;
            %pinta
            if app.AXIALButton.Value
                l = boundarymask(squeeze(app.Info.sol(app.posicionX,:,:)));
                im = mat2gray(squeeze(app.Info.rdata(app.posicionX,:,:)));
                %colormap
                cmap = [255,255,0];
                Iseg = insertObjectMask(im,l,'Color',cmap);
                imshow(Iseg,'Parent',app.UIAxesSlice);
                %imshow(squeeze(app.Info.rdata(app.posicionX,:,:)),[],'Parent',app.UIAxesSlice);
                %visboundaries(app.UIAxesSlice,squeeze(app.Info.sol(app.posicionX,:,:)),'color','y')
            elseif app.SAGITALButton.Value
                l = boundarymask(squeeze(app.Info.sol(:,app.posicionY,:)));
                im = mat2gray(squeeze(app.Info.rdata(:,app.posicionY,:)));
                %colormap
                cmap = [255,255,0];
                Iseg = insertObjectMask(im,l,'Color','y');
                imshow(Iseg,'Parent',app.UIAxesSlice);
                %imshow(squeeze(app.Info.rdata(:,app.posicionY,:)),[],'Parent',app.UIAxesSlice);
                %visboundaries(app.UIAxesSlice,squeeze(app.Info.sol(:,app.posicionY,:)),'color','y')
            else %coronal
                l = boundarymask(squeeze(app.Info.sol(:,:,app.posicionZ)));
                im = mat2gray(squeeze(app.Info.rdata(:,:,app.posicionZ)));
                %colormap
                cmap = [255,255,0];
                Iseg = insertObjectMask(im,l,'Color',cmap);
                imshow(Iseg,'Parent',app.UIAxesSlice);
                %imshow(squeeze(app.Info.rdata(:,:,app.posicionZ)),[],'Parent',app.UIAxesSlice);
                %visboundaries(app.UIAxesSlice,squeeze(app.Info.sol(:,:,app.posicionZ)),'color','y')

            end
            %actualiza volumen segmentado
            app.VolumenTextArea.Value = num2str(sum(app.Info.sol(:))*prod(app.Info.spacings)*1e-3);
            
        end
        
        function refrescaSlider(app)

            if app.AXIALButton.Value
                %slider axial
                app.SliceSlider.Value = app.posicionX;
                app.SliceSlider.Limits = [1 app.DIM(1)];
                app.SliceSlider.Value = app.posicionX;
            elseif app.SAGITALButton.Value 
                %slider sagital
                app.SliceSlider.Value =app.posicionY;
                app.SliceSlider.Limits = [1 app.DIM(2)];
                app.SliceSlider.Value = app.posicionY;
            else 
                %slider coronal
                app.SliceSlider.Value = app.posicionZ;
                app.SliceSlider.Limits = [1 app.DIM(3)];
                app.SliceSlider.Value = app.posicionZ;
            end

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Info)
            app.CallingApp = mainapp;
            app.Info = Info;
            app.Info.sol = app.Info.Vseg;
            app.DIM = size(Info.rdata);

            %inicializa posiciones
            app.posicionX=floor(app.DIM(1)/2);
            app.posicionY=floor(app.DIM(2)/2);
            app.posicionZ=floor(app.DIM(3)/2);

            %change vista buttons
            app.AXIALButton.Value = true;

            %configura axes
            [~,name,~] = fileparts(app.Info.name);
            app.UIAxesSlice.Title.String = '';
            app.PanelImage.Title = name;
            app.UIAxesSlice.YLabel.String = '';
            app.UIAxesSlice.XLabel.String = ''; 

            imrefresca(app);

            
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            if app.AXIALButton.Value
                app.posicionX = floor(app.SliceSlider.Value);

            elseif app.SAGITALButton.Value
                app.posicionY = floor(app.SliceSlider.Value);

            else
                app.posicionZ = floor(app.SliceSlider.Value);
            end

            imrefresca(app);
        end

        % Button pushed function: PerfilarButton
        function PerfilarButtonPushed(app, event)
            if app.AXIALButton.Value
                Slice = squeeze(app.Info.sol(app.posicionX,:,:));
                h = drawfreehand(app.UIAxesSlice,'Closed',true,'LineWidth',5,'Color','r','Multiclick',false);
                newMask = createMask(h, Slice);
                delete(h)
                Slice(newMask>0)=1;
                app.Info.sol(app.posicionX,:,:) = (Slice==1);
                
            elseif app.SAGITALButton.Value 
                Slice = squeeze(app.Info.sol(:,app.posicionY,:));
                h = drawfreehand(app.UIAxesSlice,'Closed',true,'LineWidth',5,'Color','r','Multiclick',false);
                newMask = createMask(h, Slice);
                delete(h)
                Slice(newMask>0)=1;
                app.Info.sol(:,app.posicionY,:) = (Slice==1);
                
            elseif app.CORONALButton.Value == true
                Slice = squeeze(app.Info.sol(:,:,app.posicionZ));
                h = drawfreehand(app.UIAxesSlice,'Closed',true,'LineWidth',5,'Color','r','Multiclick',false);
                newMask = createMask(h, Slice);
                delete(h)
                Slice(newMask>0)=1;
                app.Info.sol(:,:,app.posicionZ) = (Slice==1);          
            end

            imrefresca(app);
        end

        % Button pushed function: RecortarButton
        function RecortarButtonPushed(app, event)
           if app.AXIALButton.Value == true
                Slice = squeeze(app.Info.sol(app.posicionX,:,:));
                h = drawfreehand(app.UIAxesSlice,'Closed',true,'LineWidth',5,'Color','r','Multiclick',false);
                newMask = createMask(h, Slice);
                delete(h)
                Slice(newMask>0)=0;
                app.Info.sol(app.posicionX,:,:) = (Slice==1);
                
            elseif app.SAGITALButton.Value == true
                Slice = squeeze(app.Info.sol(:,app.posicionY,:));
                h = drawfreehand(app.UIAxesSlice,'Closed',true,'LineWidth',5,'Color','r','Multiclick',false);
                newMask = createMask(h, Slice);
                delete(h)
                Slice(newMask>0)=0;
                app.Info.sol(:,app.posicionY,:) = (Slice==1);
                
            elseif app.CORONALButton.Value == true
                Slice = squeeze(app.Info.sol(:,:,app.posicionZ));
                h = drawfreehand(app.UIAxesSlice,'Closed',true,'LineWidth',5,'Color','r','Multiclick',false);
                newMask = createMask(h, Slice);
                delete(h)
                Slice(newMask>0)=0;
                app.Info.sol(:,:,app.posicionZ) = (Slice==1);
           end
           imrefresca(app);
        end

        % Button pushed function: DibujarElipseButton
        function DibujarElipseButtonPushed(app, event)
            if app.AXIALButton.Value == true
                Slice = squeeze(app.Info.sol(app.posicionX,:,:));
                h = drawellipse(app.UIAxesSlice);
                wait(h);
                newMask = createMask(h, Slice);
                Slice(newMask>0) = 1; 
                app.Info.sol(app.posicionX,:,:) = (Slice==1);

            elseif app.SAGITALButton.Value == true
                Slice = squeeze(app.Info.sol(:,app.posicionY,:));
                h = drawellipse(app.UIAxesSlice);
                wait(h);
                newMask = createMask(h, Slice);
                Slice(newMask>0) = 1; 
                app.Info.sol(:,app.posicionY,:) = (Slice==1);
            elseif app.CORONALButton.Value == true
                Slice = squeeze(app.Info.sol(:,:,app.posicionZ));
                h = drawellipse(app.UIAxesSlice);
                wait(h);
                newMask = createMask(h, Slice);
                Slice(newMask>0) = 1; 
                app.Info.sol(:,:,app.posicionZ) = (Slice==1);
            end
            imrefresca(app);
        end

        % Button pushed function: AnteriorButton
        function AnteriorButtonPushed(app, event)
            if app.SliceSlider.Value > 1
                if app.AXIALButton.Value
                    app.posicionX = app.posicionX - 1;
                elseif app.SAGITALButton.Value
                    app.posicionY = app.posicionY - 1;
                else
                    app.posicionZ = app.posicionZ - 1;
                end
                imrefresca(app);
            end
        end

        % Button pushed function: SiguienteButton
        function SiguienteButtonPushed(app, event)
            
            if app.AXIALButton.Value
                if app.SliceSlider.Value < app.DIM(1)
                    app.posicionX = app.posicionX + 1;
                    imrefresca(app);
                end
            elseif app.SAGITALButton.Value
                if app.SliceSlider.Value < app.DIM(2)
                    app.posicionY = app.posicionY + 1;
                    imrefresca(app);
                end
            else
                if app.SliceSlider.Value < app.DIM(3)
                    app.posicionZ = app.posicionZ + 1;
                    imrefresca(app);
                end
            end     
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            %actualiza campos
            f = waitbar(0.30,'Guardando los datos');
            app.Info.Vseg = app.Info.sol;
            [~,~,V123]=volumen_elipse_segmentado1(app.Info.Vseg);
            app.Info.Velipsoide=genera_elipsoide(borde(V123));
            app.Info.volumen = sum(app.Info.Velipsoide(:))*prod(app.Info.spacings)*1e-3;
            app.Info = rmfield(app.Info, 'sol');
            updateInfo(app.CallingApp,app.Info);
            close(f)
            delete(app)
        end

        % Selection changed function: VistaButtonGroup
        function VistaButtonGroupSelectionChanged(app, event)
            imrefresca(app);
        end

        % Button pushed function: DeshacertodosloscambiosButton
        function DeshacertodosloscambiosButtonPushed(app, event)
            if app.AXIALButton.Value
                app.Info.sol(app.posicionX,:,:) = app.Info.Vseg(app.posicionX,:,:);

            elseif app.SAGITALButton.Value
                app.Info.sol(:,app.posicionY,:) = app.Info.Vseg(:,app.posicionY,:);
                
            elseif app.CORONALButton.Value 
                app.Info.sol(:,:,app.posicionZ) = app.Info.Vseg(:,:,app.posicionZ);

            end
            imrefresca(app);
        end

        % Button pushed function: CopiaranteriorButton
        function CopiaranteriorButtonPushed(app, event)
            if app.SliceSlider.Value > 1

                if app.AXIALButton.Value
                    app.Info.sol(app.posicionX,:,:) = app.Info.sol(app.posicionX-1,:,:);
                  
                elseif app.SAGITALButton.Value
                    app.Info.sol(:,app.posicionY,:) = app.Info.sol(:,app.posicionY-1,:);

                else
                    app.Info.sol(:,:,app.posicionZ) = app.Info.sol(:,:,app.posicionZ-1);                  
                end
            end
            imrefresca(app);
        end

        % Button pushed function: CopiarsiguienteButton
        function CopiarsiguienteButtonPushed(app, event)
            if app.AXIALButton.Value
                if app.SliceSlider.Value < app.DIM(1)
                    app.Info.sol(app.posicionX,:,:) = app.Info.sol(app.posicionX+1,:,:);
                    imrefresca(app);
                end

            elseif app.SAGITALButton.Value
                if app.SliceSlider.Value < app.DIM(2)
                    app.Info.sol(:,app.posicionY,:) = app.Info.sol(:,app.posicionY+1,:);
                    imrefresca(app);
                end

            else
                if app.SliceSlider.Value < app.DIM(3)
                    app.Info.sol(:,:,app.posicionZ) = app.Info.sol(:,:,app.posicionZ+1);
                    imrefresca(app);
                end
            end    
            imrefresca(app);
        end

        % Button pushed function: AumentarButton
        function AumentarButtonPushed(app, event)
            if app.AXIALButton.Value
                app.Info.sol(app.posicionX,:,:) = imdilate(squeeze(app.Info.sol(app.posicionX,:,:)), strel('disk',3));

            elseif app.SAGITALButton.Value
                app.Info.sol(:,app.posicionY,:) = imdilate(squeeze(app.Info.sol(:,app.posicionY,:)), strel('disk',3));
               
            elseif app.CORONALButton.Value
                app.Info.sol(:,:,app.posicionZ) = imdilate(squeeze(app.Info.sol(:,:,app.posicionZ)), strel('disk',3));
            end

            imrefresca(app);
        end

        % Button pushed function: DisminuirButton
        function DisminuirButtonPushed(app, event)
            if app.AXIALButton.Value
                app.Info.sol(app.posicionX,:,:) = imerode(squeeze(app.Info.sol(app.posicionX,:,:)), strel('disk',3));
                
            elseif app.SAGITALButton.Value
                app.Info.sol(:,app.posicionY,:) = imerode(squeeze(app.Info.sol(:,app.posicionY,:)), strel('disk',3));
               
            elseif app.CORONALButton.Value
                app.Info.sol(:,:,app.posicionZ) = imerode(squeeze(app.Info.sol(:,:,app.posicionZ)), strel('disk',3));
                
            end

            imrefresca(app);
        end

        % Button pushed function: SuavizarButton
        function SuavizarButtonPushed(app, event)
            if app.AXIALButton.Value
                app.Info.sol(app.posicionX,:,:) = imclose(squeeze(app.Info.sol(app.posicionX,:,:)), strel('disk',12));
                
            elseif app.SAGITALButton.Value
                app.Info.sol(:,app.posicionY,:) = imclose(squeeze(app.Info.sol(:,app.posicionY,:)), strel('disk',12));
                
            elseif app.CORONALButton.Value
                app.Info.sol(:,:,app.posicionZ) = imclose(squeeze(app.Info.sol(:,:,app.posicionZ)), strel('disk',12));
                
            end

            imrefresca(app);
        end

        % Button pushed function: AumentarButton_2
        function AumentarButton_2Pushed(app, event)
            
            app.Info.sol = imdilate(app.Info.sol,strel('sphere',3));
            imrefresca(app);
        end

        % Button pushed function: DisminuirButton_2
        function DisminuirButton_2Pushed(app, event)
            app.Info.sol = imerode(app.Info.sol,strel('sphere',3));
            imrefresca(app);
        end

        % Button pushed function: SuavizarButton_2
        function SuavizarButton_2Pushed(app, event)
            app.Info.sol = imclose(app.Info.sol,strel('cube',9));
            imrefresca(app);
        end

        % Button pushed function: SegmentacinsemiautomticaButton
        function SegmentacinsemiautomticaButtonPushed(app, event)
            f = msgbox('Dibuje una elipse que represente el cráneo.');
            if app.AXIALButton.Value 
                Im = squeeze(app.Info.rdata(app.posicionX,:,:));
                h= drawellipse(app.UIAxesSlice);
                wait(h)
                app.Info.sol(app.posicionX,:,:)  = Segmentacion_RW2(Im,h);

             elseif app.SAGITALButton.Value
                Im = squeeze(app.Info.rdata(:,app.posicionY,:));
                h= drawellipse(app.UIAxesSlice);
                wait(h)
                app.Info.sol(:,app.posicionY,:)  = Segmentacion_RW2(Im,h);

            else
                Im = squeeze(app.Info.rdata(:,:,app.posicionZ));
                h= drawellipse(app.UIAxesSlice);
                wait(h)
                app.Info.sol(:,:,app.posicionZ)  = Segmentacion_RW2(Im,h);
            end
            imrefresca(app);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.ModificarSegmentacionButton.Enable = 'on';
            delete(app)
            
        end

        % Button pushed function: VerButton
        function VerButtonPushed(app, event)
            volshow(app.Info.sol,'Parent',app.VolumenPanel);
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            position = app.VolumenPanel.Layout;
            delete(app.VolumenPanel);
            app.VolumenPanel = uipanel(app.GridLayout);
            app.VolumenPanel.Layout = position;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.VistaButtonGroup = uibuttongroup(app.GridLayout);
            app.VistaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VistaButtonGroupSelectionChanged, true);
            app.VistaButtonGroup.Title = 'Vista';
            app.AXIALButton = uiradiobutton(app.VistaButtonGroup);
            app.AXIALButton.Text = 'AXIAL';
            app.AXIALButton.Value = true;
            app.SAGITALButton = uiradiobutton(app.VistaButtonGroup);
            app.SAGITALButton.Text = 'SAGITAL';
            app.CORONALButton = uiradiobutton(app.VistaButtonGroup);
            app.CORONALButton.Text = 'CORONAL';
            app.OpcionesSlicePanel = uipanel(app.GridLayout);
            app.OpcionesSlicePanel.Title = 'Opciones Slice';
            app.PerfilarButton = uibutton(app.OpcionesSlicePanel, 'push');
            app.PerfilarButton.ButtonPushedFcn = createCallbackFcn(app, @PerfilarButtonPushed, true);
            app.PerfilarButton.Text = 'Perfilar';
            app.RecortarButton = uibutton(app.OpcionesSlicePanel, 'push');
            app.RecortarButton.ButtonPushedFcn = createCallbackFcn(app, @RecortarButtonPushed, true);
            app.RecortarButton.Text = 'Recortar';
            app.DibujarElipseButton = uibutton(app.OpcionesSlicePanel, 'push');
            app.DibujarElipseButton.ButtonPushedFcn = createCallbackFcn(app, @DibujarElipseButtonPushed, true);
            app.DibujarElipseButton.Text = 'Dibujar Elipse';
            app.DeshacertodosloscambiosButton = uibutton(app.OpcionesSlicePanel, 'push');
            app.DeshacertodosloscambiosButton.ButtonPushedFcn = createCallbackFcn(app, @DeshacertodosloscambiosButtonPushed, true);
            app.DeshacertodosloscambiosButton.Text = 'Deshacer todos los cambios';
            app.AumentarButton = uibutton(app.OpcionesSlicePanel, 'push');
            app.AumentarButton.ButtonPushedFcn = createCallbackFcn(app, @AumentarButtonPushed, true);
            app.AumentarButton.Text = 'Aumentar';
            app.DisminuirButton = uibutton(app.OpcionesSlicePanel, 'push');
            app.DisminuirButton.ButtonPushedFcn = createCallbackFcn(app, @DisminuirButtonPushed, true);
            app.DisminuirButton.Text = 'Disminuir';
            app.SuavizarButton = uibutton(app.OpcionesSlicePanel, 'push');
            app.SuavizarButton.ButtonPushedFcn = createCallbackFcn(app, @SuavizarButtonPushed, true);
            app.SuavizarButton.Text = 'Suavizar';
            app.SegmentacinsemiautomticaButton = uibutton(app.OpcionesSlicePanel, 'push');
            app.SegmentacinsemiautomticaButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentacinsemiautomticaButtonPushed, true);
            app.SegmentacinsemiautomticaButton.Text = 'Segmentación semi-automática';
            app.SiguienteButton = uibutton(app.GridLayout, 'push');
            app.SiguienteButton.ButtonPushedFcn = createCallbackFcn(app, @SiguienteButtonPushed, true);
            app.SiguienteButton.Text = 'Siguiente';
            app.AnteriorButton = uibutton(app.GridLayout, 'push');
            app.AnteriorButton.ButtonPushedFcn = createCallbackFcn(app, @AnteriorButtonPushed, true);
            app.AnteriorButton.Text = 'Anterior';
            app.GuardarButton = uibutton(app.GridLayout, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.CopiaranteriorButton = uibutton(app.GridLayout, 'push');
            app.CopiaranteriorButton.ButtonPushedFcn = createCallbackFcn(app, @CopiaranteriorButtonPushed, true);
            app.CopiaranteriorButton.Text = 'Copiar anterior';
            app.CopiarsiguienteButton = uibutton(app.GridLayout, 'push');
            app.CopiarsiguienteButton.ButtonPushedFcn = createCallbackFcn(app, @CopiarsiguienteButtonPushed, true);
            app.CopiarsiguienteButton.Text = 'Copiar siguiente';
            app.VolumenPanel = uipanel(app.GridLayout);
            app.VolumenPanel.Title = 'Volumen';
            app.SliceSlider = uislider(app.GridLayout);
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.Value = 96;
            app.OpcionesVolumenPanel = uipanel(app.GridLayout);
            app.OpcionesVolumenPanel.Title = 'Opciones Volumen';
            app.AumentarButton_2 = uibutton(app.OpcionesVolumenPanel, 'push');
            app.AumentarButton_2.ButtonPushedFcn = createCallbackFcn(app, @AumentarButton_2Pushed, true);
            app.AumentarButton_2.Text = 'Aumentar';
            app.DisminuirButton_2 = uibutton(app.OpcionesVolumenPanel, 'push');
            app.DisminuirButton_2.ButtonPushedFcn = createCallbackFcn(app, @DisminuirButton_2Pushed, true);
            app.DisminuirButton_2.Text = 'Disminuir';
            app.VolumenTextArea = uitextarea(app.OpcionesVolumenPanel);
            app.VolumenSegmentadoLabel = uilabel(app.OpcionesVolumenPanel);
            app.VolumenSegmentadoLabel.Text = 'Volumen Segmentado';
            app.SuavizarButton_2 = uibutton(app.OpcionesVolumenPanel, 'push');
            app.SuavizarButton_2.ButtonPushedFcn = createCallbackFcn(app, @SuavizarButton_2Pushed, true);
            app.SuavizarButton_2.Text = 'Suavizar';
            app.VerButton = uibutton(app.OpcionesVolumenPanel, 'push');
            app.VerButton.ButtonPushedFcn = createCallbackFcn(app, @VerButtonPushed, true);
            app.VerButton.Text = 'Ver';
            app.CerrarButton = uibutton(app.OpcionesVolumenPanel, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.PanelImage = uipanel(app.GridLayout);
            app.UIAxesSlice = uiaxes(app.PanelImage);
            title(app.UIAxesSlice, 'Title')
            xlabel(app.UIAxesSlice, 'X')
            ylabel(app.UIAxesSlice, 'Y')
            zlabel(app.UIAxesSlice, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EditSegmentation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end